classdef EpochViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        LoadDataMenu_2                  matlab.ui.container.Menu
        SortFilterMenu                  matlab.ui.container.Menu
        RowsMenu                        matlab.ui.container.Menu
        ColumnsMenu                     matlab.ui.container.Menu
        OptionsMenu                     matlab.ui.container.Menu
        ResetViewerMenu                 matlab.ui.container.Menu
        ViewGlassBrainMenu              matlab.ui.container.Menu
        BrainViewerMenu                 matlab.ui.container.Menu
        RereferenceMenu_2               matlab.ui.container.Menu
        NoneMenu_2                      matlab.ui.container.Menu
        CARMenu_2                       matlab.ui.container.Menu
        GARMenu_2                       matlab.ui.container.Menu
        PARMenu_2                       matlab.ui.container.Menu
        LaplacianMenu_2                 matlab.ui.container.Menu
        BipolarMenu_2                   matlab.ui.container.Menu
        CMRMenu_2                       matlab.ui.container.Menu
        BanksSpecialMenu_2              matlab.ui.container.Menu
        InDevelopmentMenu               matlab.ui.container.Menu
        ViewSpectrogramMenu             matlab.ui.container.Menu
        SIGNIMenu                       matlab.ui.container.Menu
        ChangeColormapMenu              matlab.ui.container.Menu
        ParulaMenu                      matlab.ui.container.Menu
        JetMenu                         matlab.ui.container.Menu
        DebugMenu                       matlab.ui.container.Menu
        ZScoreModuleMenu                matlab.ui.container.Menu
        Panel3                          matlab.ui.container.Panel
        YLimLabel                       matlab.ui.control.Label
        TimeLabel                       matlab.ui.control.Label
        UpperLabel                      matlab.ui.control.Label
        LowerLabel                      matlab.ui.control.Label
        TimeLowerEditField              matlab.ui.control.NumericEditField
        TimeUpperEditField              matlab.ui.control.NumericEditField
        YLimUpperEditField              matlab.ui.control.NumericEditField
        YLimLowerEditField              matlab.ui.control.NumericEditField
        ColorAxisButtonGroup_3          matlab.ui.container.ButtonGroup
        ManualButton_3                  matlab.ui.control.ToggleButton
        AutoButton_3                    matlab.ui.control.ToggleButton
        UIAxes3                         matlab.ui.control.UIAxes
        Panel2                          matlab.ui.container.Panel
        UpperColorAxisEditField_2       matlab.ui.control.NumericEditField
        LowerColorAxisEditField_2       matlab.ui.control.NumericEditField
        ColorAxisButtonGroup_2          matlab.ui.container.ButtonGroup
        ManualButton_2                  matlab.ui.control.ToggleButton
        AutoButton_2                    matlab.ui.control.ToggleButton
        Dimension                       matlab.ui.container.ButtonGroup
        ExperimentsButton               matlab.ui.control.ToggleButton
        ChannelsButton                  matlab.ui.control.ToggleButton
        UpperColorAxisEditField_2Label  matlab.ui.control.Label
        LowerColorAxisEditField_2Label  matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        Panel1                          matlab.ui.container.Panel
        UpperColorAxisEditField         matlab.ui.control.NumericEditField
        LowerColorAxisEditField         matlab.ui.control.NumericEditField
        ColorAxisButtonGroup            matlab.ui.container.ButtonGroup
        ManualButton                    matlab.ui.control.ToggleButton
        AutoButton                      matlab.ui.control.ToggleButton
        UpperColorAxisEditFieldLabel    matlab.ui.control.Label
        LowerColorAxisEditFieldLabel    matlab.ui.control.Label
        ColorScaleButtonGroup           matlab.ui.container.ButtonGroup
        LogarithmicButton               matlab.ui.control.ToggleButton
        LinearButton                    matlab.ui.control.ToggleButton
        UIAxes1                         matlab.ui.control.UIAxes
        UIAxes1ContextMenu              matlab.ui.container.ContextMenu
        SelectMeasureMenu               matlab.ui.container.Menu
        AbsoluteMaxMenu                 matlab.ui.container.Menu
        MaximumMenu                     matlab.ui.container.Menu
        MinimumMenu                     matlab.ui.container.Menu
        RangeMenu                       matlab.ui.container.Menu
        RMSMenu                         matlab.ui.container.Menu
        RMSPeakMenu                     matlab.ui.container.Menu
        SNRMenu                         matlab.ui.container.Menu
        CVMenu                          matlab.ui.container.Menu
        LoadedDataMenu                  matlab.ui.container.Menu
        MarkersMenu                     matlab.ui.container.Menu
        LoadMarkersMenu                 matlab.ui.container.Menu
        ExportMarkersMenu               matlab.ui.container.Menu
        ResetMarkersMenu                matlab.ui.container.Menu
        LoadDataMenu                    matlab.ui.container.Menu
        UIAxes2ContextMenu              matlab.ui.container.ContextMenu
        SummaryMenu                     matlab.ui.container.Menu
        MeanMenu                        matlab.ui.container.Menu
        PhaseSynchronyMenu              matlab.ui.container.Menu
        MedianMenu                      matlab.ui.container.Menu
        RMSMenu_2                       matlab.ui.container.Menu
        CRPSbarMenu                     matlab.ui.container.Menu
        ShuffleViewerMenu               matlab.ui.container.Menu
        UIAxes3ContextMenu              matlab.ui.container.ContextMenu
        ShowHideMeanMenu                matlab.ui.container.Menu
        ViewingWindowMenu               matlab.ui.container.Menu
        FullMenu                        matlab.ui.container.Menu
        BoundedMenu                     matlab.ui.container.Menu
        SortTrialsMenu                  matlab.ui.container.Menu
        LoadMenu                        matlab.ui.container.Menu
        DefaultMenu                     matlab.ui.container.Menu
        LoadedMenu                      matlab.ui.container.Menu
        VoltageMenu                     matlab.ui.container.Menu
    end

    
    properties (Access = public)
        SubjectID
        TaskFolder

        OriginalData
        CurrentData
        
        OriginalSummaryEpoch
        summaryEpoch
        summaryEpoch1
        
        mathPEP1
        mathPEP2
        
        OriginalLabels
        CurrentLabels
        
        OriginalTable
        CurrentTable
        
        GroupLabels
        GroupIndices
        ContactLabels
        ContactIndices
        
        xAxis
        Colorbar1
        Colorbar2
        SelectedDimension = 'Channels'
        Rectangle1
        Rectangle2
        TimeWindowIndices
        LoadedPEPData
        MarkerTable
        MarkerPlots
        MarkerIndex
        SelectedMeasure = 'Absolute Max'
        
        DefaultTrialPerm
        LoadedTrialPerm
        CurrentTrialPerm
        
        RereferencingMethod
        
        ColumnOrder
        RowOrder
        
        CurrentRow = 1
        CurrentColumn = 1
        
        Axis1Measure = 'Absolute Max'
        
        ProgressDLG
        
    end
    
    properties (Access = public)
        ReorderDataApp
        SortFilterTableApp
        GlassBrainApp
        BrainViewerApp
        SpectrogramViewerApp
    end
    
    methods (Access = private)
        
        function loadData(app)
            if isempty(app.SubjectID)
                subjectFolder = uigetfilesfolders(pwd,'DIRECTORIES_ONLY','Select Patient Folder');
            else
                subjectFolder = uigetfilesfolders(app.TaskFolder,'DIRECTORIES_ONLY','Select Patient Folder');
            end
            if isempty(subjectFolder)
                return
            end
            app.SubjectID = subjectFolder.name;
            app.TaskFolder = subjectFolder.folder;
            files = dir(fullfile(subjectFolder.folder,subjectFolder.name,'EpochedData'));
            files([files.isdir]) = [];
            
            if length(files)>1
                app.ReorderDataApp = ReorderList({files.name},app);
                waitfor(app.ReorderDataApp)
            else
                app.ColumnOrder = 1;
            end

            epochedFiles = files(app.ColumnOrder);
            [~,labels] = cellfun(@(x) fileparts(x),{epochedFiles.name},'Uni',0);

            % Check for commonalities in row data and keep those ONLY
            intersection = [];
            if length(files)>1
                for f = 1:length(epochedFiles)
                    tmp = load(fullfile(epochedFiles(f).folder,epochedFiles(f).name),'MontageInfo');
                    if isempty(intersection)
                        intersection = tmp.MontageInfo.Current.Label;
                    else
                        intersection = intersect(intersection,tmp.MontageInfo.Current.Label,'stable');
                    end
                end
            end
            
            d = uiprogressdlg(app.UIFigure,'Title','Loading Data','Value',0);
            allFiles = cell(length(epochedFiles),1);
            for f = 1:length(epochedFiles)
                load(fullfile(epochedFiles(f).folder,epochedFiles(f).name),'MontageInfo');
                [~,ia] = intersect(MontageInfo.Current.Label,intersection,'stable');
                tmp = load(fullfile(epochedFiles(f).folder,epochedFiles(f).name),'Data');
                allFiles{f} = tmp.Data(ia,:,:);
                d.Value = f/length(epochedFiles);
            end
            d.Indeterminate = 'on';
            tmp = load(fullfile(epochedFiles(f).folder,epochedFiles(f).name),'xAxis');
            app.xAxis = tmp.xAxis;
            
            app.OriginalLabels = labels;
            [~,ia] = intersect(MontageInfo.Current.Label,intersection,'stable');
            app.OriginalTable = MontageInfo.Current(ia,:);
            app.OriginalTable.MatrixID = [1:size(app.OriginalTable,1)]';
            app.OriginalData = allFiles;
            
            app.DefaultTrialPerm = cellfun(@(x) repmat(1:size(x,3),[size(x,1) 1])',app.OriginalData,'Uni',0);
            close(d)
        end


        function checkLoadingData(app)
            
        end
        

        function resetApp(app)
            d = uiprogressdlg(app.UIFigure,'Title','Resetting App','Indeterminate','on');
            % Clear/Delete Secondary Apps/Windows
            if ~isempty(app.ReorderDataApp)
                delete(app.ReorderDataApp)
                app.ReorderDataApp = [];
            end
            
            if ~isempty(app.SortFilterTableApp)
                delete(app.SortFilterTableApp)
                app.SortFilterTableApp = [];
            end
            
            if ~isempty(app.GlassBrainApp)
                delete(app.GlassBrainApp)
                app.GlassBrainApp = [];
            end

            if ~isempty(app.BrainViewerApp)
                delete(app.BrainViewerApp)
                app.BrainViewerApp = [];
            end
            
            % Reset Properties
            app.CurrentLabels = app.OriginalLabels;
            app.CurrentTable = app.OriginalTable;
            app.TimeWindowIndices = 1:length(app.xAxis);

            app.TimeLowerEditField.Limits = [app.xAxis(1) app.xAxis(end)];
            app.TimeUpperEditField.Limits = [app.xAxis(1) app.xAxis(end)];
            app.TimeLowerEditField.Value = app.xAxis(1);
            app.TimeUpperEditField.Value = app.xAxis(end);

            app.GroupLabels = app.CurrentTable.Label;
            app.GroupIndices = num2cell(app.CurrentTable.MatrixID);
            
            app.ContactLabels = app.CurrentTable.Label;
            app.ContactIndices = app.CurrentTable.MatrixID;
            
            app.CurrentData = cell(size(app.OriginalData));
            for i = 1:length(app.CurrentData)
                app.CurrentData{i} = permute(app.OriginalData{i}(app.ContactIndices,:,:),[3 2 1]);
            end
            
            app.CurrentTrialPerm = app.DefaultTrialPerm;
            
            app.ColumnOrder = 1:length(app.CurrentData);
            
            app.CurrentRow = 1;
            app.CurrentColumn = 1;
            
            app.MarkerTable = [];
            close(d)
        end
        

        function summarizeEpochs(app)
            d = uiprogressdlg(app.UIFigure,'Title','Summarizing Data','Value',0);
            ind = ismember([app.SummaryMenu.Children.Checked],'on');
            tmp = cell(size(app.CurrentData));
            switch app.SummaryMenu.Children(ind).Text
                case {'Mean','Median','RMS'}
                    fn = eval(['@',lower(app.SummaryMenu.Children(ind).Text)]);
                    for i = 1:length(app.CurrentData)
                        d.Value = i/length(app.CurrentData);
                        tmp1 = permute(app.CurrentData{i},[3 2 1]);
                        tmp{i} = fn(tmp1,3);
                    end
                case 'Phase Synchrony'
                    for i = 1:length(app.CurrentData)
                        d.Value = i/length(app.CurrentData);
                        tmp1  = permute(app.CurrentData{i},[2 1 3]);
                        tmp1a = zeros(size(tmp1,3,1));
                        for j = 1:length(app.ContactIndices)
                            tmp2 = hilbert(tmp1(:,:,j));
                            tmp3 = angle(tmp2);
                            tmp4 = sum(ones(size(tmp3)).*exp(1i*tmp3),2);
                            tmp1a(j,:) = abs(tmp4);
                        end
                        tmp{i} = tmp1a;
                    end
                case 'CRP Sbar'
                    processingWindow = find((app.xAxis>=10 & app.xAxis<=400));
                    for i = 1:length(app.CurrentData)
                        d.Value = i/length(app.CurrentData);
                        tmp1  = permute(app.CurrentData{i},[2 1 3]);
                        tmp1a = zeros(size(tmp1,3,1));
                        for j = 1:length(app.ContactIndices)
                            trials = tmp1(processingWindow,:,j);
                            trialsNormalized = trials./abs(trials);
                            trialsNormalized(isnan(trialsNormalized)) = 0;
                            dotProduct = zeros(size(tmp1,2),size(tmp1,2),numel(processingWindow));
                            for k = 1:numel(processingWindow)
                                dotProduct(:,:,k) = trialsNormalized(k,:)'*trials(k,:);
%                                 dotProduct = trialsNormalized(1:k,:)'*trials(1:k,:);
%                                 dotProduct(eye(size(dotProduct))==1) = nan;
%                                 tmp1a(j,processingWindow(k)) = mean(dotProduct(~isnan(dotProduct)));
                            end
                            S = cumsum(dotProduct,3);
                            for k = 1:numel(processingWindow)
                                tmp2 = S(:,:,k);
                                tmp2(eye(size(tmp2))==1) = nan;
                                tmp1a(j,processingWindow(k)) = mean(tmp2(~isnan(tmp2)));
                            end
                        end
                        tmp{i} = tmp1a;
                    end
            end
            app.summaryEpoch = cat(3,tmp{:});
            app.summaryEpoch1 = permute(app.summaryEpoch,[3 2 1]);
            close(d)
        end
        

        function setupFigures(app)
            d = uiprogressdlg(app.UIFigure,'Title','Drawing Plots','Indeterminate','on');
            cla(app.UIAxes1,'reset')
            cla(app.UIAxes2,'reset')
            cla(app.UIAxes3,'reset')
            
            
            %% PEP DATA OVERVIEW/NAVIGATION WINDOW
            app.UIAxes1.XLim = [0.5 size(app.summaryEpoch,3)+0.5];
            app.UIAxes1.YLim = [0.5 size(app.summaryEpoch,1)+0.5];
            app.UIAxes1.Toolbar.Visible = 'off';
            
            imagesc(app.UIAxes1,squeeze(max(abs(app.summaryEpoch),[],2)),'ButtonDownFcn',@SelectPoint)
            app.Colorbar1=colorbar(app.UIAxes1);

            app.UIAxes1.XTick = 1:size(app.summaryEpoch,3);
            app.UIAxes1.XTickLabel = app.CurrentLabels;
            app.UIAxes1.XTickLabelRotation = 45;
            app.UIAxes1.TickLabelInterpreter = 'none';
            fs = floor(app.UIAxes1.InnerPosition(3)/numel(app.CurrentLabels)*sin(app.UIAxes1.XTickLabelRotation));
            app.UIAxes1.XAxis.FontSize = min(max(fs,1),12);

            createContactLabels(app,'UIAxes1')
            
            app.Rectangle1 = rectangle(app.UIAxes1,'Position',[0.5 0.5 1 1],'EdgeColor','r','LineWidth',1.25);
            ind = isgraphics(app.UIAxes1.Children,'Image');
            app.MarkerPlots = patch(app.UIAxes1,zeros(4,numel(app.UIAxes1.Children(ind).CData)),zeros(4,numel(app.UIAxes1.Children(ind).CData)),[1 0 0]);
            
            
            %% Summary Waveforms for Highlighted Experiment
            app.UIAxes2.XLim = [app.xAxis(1)-0.5 app.xAxis(end)+0.5];
            app.UIAxes2.YLim = [0.5 size(app.summaryEpoch,1)+0.5];
            title(app.UIAxes2,app.CurrentLabels{1},'Interpreter','none');
            imagesc(app.UIAxes2,'XData',app.xAxis,'YData',1:size(app.summaryEpoch,1),'CData',app.summaryEpoch(:,:,1),'ButtonDownFcn',@SelectRow)
            app.UIAxes2.YDir = 'reverse';
            app.Colorbar2 = colorbar(app.UIAxes2);
            
            createContactLabels(app,'UIAxes2')
            
            app.Rectangle2 = rectangle(app.UIAxes2,'Position',[app.xAxis(1)+0.5 0.5 diff(app.UIAxes2.XLim) 1],'EdgeColor','r','LineWidth',1.25);
            
            
            %% Individual Epoched Trials of Highlighted Experiment
            app.UIAxes3.XLim = [app.xAxis(1)-0.5 app.xAxis(end)+0.5];
            app.UIAxes3.YLim = [0.5 size(app.CurrentData{1},1)+0.5];
            title(app.UIAxes3,app.ContactLabels{1},'Interpreter','none')
            imagesc(app.UIAxes3,'XData',app.xAxis,'YData',1:size(app.CurrentData{1},1),'CData',app.CurrentData{1}(:,:,1))
            
            xline(app.UIAxes3,app.xAxis(1),'Color','red','LineWidth',1)
            xline(app.UIAxes3,app.xAxis(end),'Color','red','LineWidth',1)
            yyaxis(app.UIAxes3,'right')
            plot(app.UIAxes3,app.xAxis,app.summaryEpoch(1,:,1),'r-','LineWidth',2)
            app.UIAxes3.YAxis(2).Visible = 'off';
            app.UIAxes3.Children.Visible = 'off';
            yyaxis(app.UIAxes3,'left')
            
            % Adjust Figure Axes
            if strcmp('Auto',app.ColorAxisButtonGroup.SelectedObject.Text)
                app.LowerColorAxisEditField.Value = round(app.Colorbar1.Limits(1));
                app.UpperColorAxisEditField.Value = round(app.Colorbar1.Limits(2));
            else
                caxis(app.UIAxes1,[app.LowerColorAxisEditField.Value app.UpperColorAxisEditField.Value]);
            end
            
            if strcmp('Auto',app.ColorAxisButtonGroup_2.SelectedObject.Text)
                app.LowerColorAxisEditField_2.Value = round(app.Colorbar2.Limits(1));
                app.UpperColorAxisEditField_2.Value = round(app.Colorbar2.Limits(2));
            else
                caxis(app.UIAxes2,[app.LowerColorAxisEditField_2.Value app.UpperColorAxisEditField_2.Value]);
                yyaxis(app.UIAxes3,'right')
                app.UIAxes3.YLim = [app.LowerColorAxisEditField_2.Value app.UpperColorAxisEditField_2.Value];
                yyaxis(app.UIAxes3,'left')
            end
            
            if strcmp('Auto',app.ColorAxisButtonGroup_3.SelectedObject.Text)
                ind = isgraphics(app.UIAxes3.Children,'Image');
                app.YLimLowerEditField.Value = round(min(app.UIAxes3.Children(ind).CData(:)));
                app.YLimUpperEditField.Value = round(max(app.UIAxes3.Children(ind).CData(:)));
            else
                app.UIAxes3.CLim = [app.YLimLowerEditField.Value app.YLimUpperEditField.Value];
            end
            
            function SelectPoint(~,event)
                row = ceil(event.IntersectionPoint(2)-0.49);
                col = ceil(event.IntersectionPoint(1)-0.49);
                updatePlots(app,row,col)
            end
            
            function SelectRow(~,event)
                row = ceil(event.IntersectionPoint(2)-0.49);
                updatePlots(app,row)
            end
            close(d)
        end
        
        
        function changeUIAxes1Measure(app)
            ind = isgraphics(app.UIAxes1.Children,'Image');
            switch app.Axis1Measure
                case 'Absolute Max'
                    axisMeasure = @(x) squeeze(max(abs(x),[],2));
                case 'Maximum'
                    axisMeasure = @(x) squeeze(max(x,[],2));
                case 'Minimum'
                    axisMeasure = @(x) squeeze(min(x,[],2));
                case 'Range'
                    axisMeasure = @(x) squeeze(range(x,2));
                case 'RMS'
                    axisMeasure = @(x) squeeze(rms(x,2));
                case 'RMS*Peak'
                    axisMeasure = @(x) squeeze(rms(x,2).*max(abs(x),[],2));
                case 'SNR'
                    axisMeasure = @(x) squeeze(mean(x,2)./std(x,[],2));
                case 'CV'
                    axisMeasure = @(x) squeeze(std(x,[],2)./mean(x,2));
            end
            app.UIAxes1.Children(ind).CData = axisMeasure(app.summaryEpoch(:,app.TimeWindowIndices,:));
            app.UIAxes1.Children(ind).AlphaData = 1;
            
            if app.AutoButton.Value==1
                app.LowerColorAxisEditField.Value = round(app.Colorbar1.Limits(1));
                app.UpperColorAxisEditField.Value = round(app.Colorbar1.Limits(2));
            elseif app.ManualButton.Value==1
                app.UIAxes1.CLim = [app.LowerColorAxisEditField.Value app.UpperColorAxisEditField.Value];
            end
        end

        
        function useLoadedUIAxes1Measure(app)
            ind = isgraphics(app.UIAxes1.Children,'Image');
            app.UIAxes1.Children(ind).CData = app.LoadedPEPData;
            app.UIAxes1.Children(ind).AlphaData = ~isnan(app.LoadedPEPData);

            if app.AutoButton.Value==1
                app.LowerColorAxisEditField.Value = round(app.Colorbar1.Limits(1));
                app.UpperColorAxisEditField.Value = round(app.Colorbar1.Limits(2));
            elseif app.ManualButton.Value==1
                app.UIAxes1.CLim = [app.LowerColorAxisEditField.Value app.UpperColorAxisEditField.Value];
            end
        end
        
        
        function RereferenceData(app)
            d = uiprogressdlg(app.UIFigure,'Title','Rereferencing Data','Indeterminate','on');
            idxSeeg = ismember(app.OriginalTable.Type,'sEEG');
            tmpTable = app.OriginalTable(idxSeeg,:);
            leads = unique(tmpTable.Lead);
            d.Message = 'Making Rereferenced Table';
            switch app.RereferencingMethod

                case 'Bipolar'
                    rerefTable = table();
                    for i = 1:length(leads)
                        idx = ismember(tmpTable.Lead,leads(i));
                        ltmp = tmpTable(idx,:);
                        for j = 1:size(ltmp,1)-1
                            label1 = ltmp.Label(j);
                            label2 = ltmp.Label(j+1);
                            tmp = regexp(label2,'\d+$','match');
                            ltmp.Label(j) = strcat(label1,'-',tmp{:});
                            ltmp.Coord_x(j) = mean([ltmp.Coord_x(j) ltmp.Coord_x(j+1)]);
                            ltmp.Coord_y(j) = mean([ltmp.Coord_y(j) ltmp.Coord_y(j+1)]);
                            ltmp.Coord_z(j) = mean([ltmp.Coord_z(j) ltmp.Coord_z(j+1)]);
                            ltmp.MNI305_x(j) = mean([ltmp.MNI305_x(j) ltmp.MNI305_x(j+1)]);
                            ltmp.MNI305_y(j) = mean([ltmp.MNI305_y(j) ltmp.MNI305_y(j+1)]);
                            ltmp.MNI305_z(j) = mean([ltmp.MNI305_z(j) ltmp.MNI305_z(j+1)]);
                        end
                        rerefTable = [rerefTable;ltmp(1:end-1,:)];
                    end

                case 'GAR'
                    idxGW = ismember(app.OriginalTable.Matter_vis,{'Grey','White'});
                    idxIntersect = idxSeeg & idxGW;
                    rerefTable = app.OriginalTable(idxIntersect,:);

                otherwise
                    rerefTable = tmpTable;

            end
            app.CurrentTable = rerefTable;

            d.Message = 'Performing Rereferencing';
            d.Indeterminate = 'off';
            d.Value = 0;
            switch app.RereferencingMethod
                case 'Bipolar'
                    for i = 1:length(app.OriginalData)
                        tmp1 = permute(app.OriginalData{app.ColumnOrder(i)},[3 2 1]);
                        tmp2 = zeros([size(tmp1,1,2),size(app.CurrentTable,1)]);

                        leads = unique(app.CurrentTable.Lead);
                        for j = 1:length(leads)
                            idx = ismember(app.CurrentTable.Lead,leads(j));
                            g1 = app.CurrentTable.MatrixID(idx);
                            g2 = g1+1;
                            tmp2(:,:,idx) = tmp1(:,:,g1)-tmp1(:,:,g2);
                        end
                        app.CurrentData{i} = tmp2;
                        d.Value = i/length(app.OriginalData);
                    end

                case 'CAR'
                    for i = 1:length(app.OriginalData)
                        tmp1 = permute(app.OriginalData{app.ColumnOrder(i)}(idxSeeg,:,:),[3 2 1]);
                        app.CurrentData{i} = tmp1-mean(tmp1,3);
                        d.Value = i/length(app.OriginalData);
                    end

                case 'CMR'
                    for i = 1:length(app.OriginalData)
                        tmp1 = permute(app.OriginalData{app.ColumnOrder(i)}(idxSeeg,:,:),[3 2 1]);
                        app.CurrentData{i} = tmp1-median(tmp1,3);
                        d.Value = i/length(app.OriginalData);
                    end

                case 'PAR'
                    for i = 1:length(app.OriginalData)
                        tmp1 = permute(app.OriginalData{app.ColumnOrder(i)}(idxSeeg,:,:),[3 2 1]);
                        leads = unique(app.CurrentTable.Lead);
                        for j = 1:length(leads)
                            idx = ismember(app.CurrentTable.Lead,leads(j));
                            tmp1(:,:,idx) = tmp1(:,:,idx)-mean(tmp1(:,:,idx),3);
                        end
                        app.CurrentData{i} = tmp1;
                        d.Value = i/length(app.OriginalData);
                    end

                case 'Laplacian'
                    for i = 1:length(app.OriginalData)
                        tmp1 = permute(app.OriginalData{app.ColumnOrder(i)}(idxSeeg,:,:),[3 2 1]);
                        tmp2 = zeros(size(tmp1));
                        leads = unique(app.CurrentTable.Lead);
                        for j = 1:length(leads)
                            idx = ismember(app.CurrentTable.Lead,leads(j));
                            a = tmp1(:,:,idx);
                            b = (a(:,:,1:sum(idx)-2)+a(:,:,3:sum(idx)))/2;
                            c = a(:,:,[1 sum(idx)-1])-a(:,:,[2 sum(idx)]);
                            a(:,:,2:sum(idx)-1) = a(:,:,2:sum(idx)-1)-b;
                            a(:,:,[1 sum(idx)]) = c;
                            tmp2(:,:,idx) = a;
                        end
                        app.CurrentData{i} = tmp2;
                        d.Value = i/length(app.OriginalData);
                    end
                
                case 'GAR'
                    idxW = ismember(app.CurrentTable.Matter_vis,'White');
                    for i = 1:length(app.OriginalData)
                        tmp1 = permute(app.OriginalData{app.ColumnOrder(i)}(idxIntersect,:,:),[3 2 1]);
                        tmp1 = tmp1-mean(tmp1(:,:,idxW),3);
                        app.CurrentData{i} = tmp1;
                        d.Value = i/length(app.OriginalData);
                    end

                case 'Banks Special'
                    idxLeftHem = ismember(app.OriginalTable.Hemisphere,'Left');
                    idxRightHem = ismember(app.OriginalTable.Hemisphere,'Right');
                    idxGreyMatter = ismember(app.OriginalTable.Matter_vis,'Grey');
                    idxWhiteMatter = ismember(app.OriginalTable.Matter_vis,'White');
                    coord = [app.OriginalTable.Coord_x app.OriginalTable.Coord_y app.OriginalTable.Coord_z];
                    dist = squareform(pdist(coord));
                    indG = find(idxGreyMatter);
                    map = zeros(numel(indG),2);
                    for i = 1:numel(indG)
                        [~,indOrd] = sort(dist(indG(i),:));
                        if idxLeftHem(indG(i))
                            tmp = idxLeftHem & idxWhiteMatter;
                        else
                            tmp = idxRightHem & idxWhiteMatter;
                        end
                        tmp = indOrd(tmp);
                        map(i,:) = [indG(i) tmp(1)];
                    end

                    for i = 1:length(app.OriginalData)
                        tmp1 = permute(app.OriginalData{app.ColumnOrder(i)},[3 2 1]);
                        tmp2 = zeros([size(tmp1,1,2),numel(indG)]);
                        for j = 1:numel(indG)
                            tmp2(:,:,j) = tmp1(:,:,map(j,1))-tmp1(:,:,map(j,2));
                        end
                        app.CurrentData{i} = tmp2;
                        d.Value = i/length(app.OriginalData);
                    end

                    app.CurrentTable = app.OriginalTable(idxSeeg & idxGreyMatter,:);

                case 'None'
                    for i = 1:length(app.OriginalData)
                        tmp1 = permute(app.OriginalData{app.ColumnOrder(i)}(idxSeeg,:,:),[3 2 1]);
                        app.CurrentData{i} = tmp1;
                        d.Value = i/length(app.OriginalData);
                    end

            end

            app.GroupLabels = app.CurrentTable.Label;
            app.GroupIndices = num2cell(app.CurrentTable.MatrixID);
            app.ContactLabels = app.CurrentTable.Label;
            app.ContactIndices = app.RowOrder;
            close(d)
        end
        
        
        function MarkTable(app) %% Something is off with this function
            idx = isgraphics(app.UIAxes1.Children,'Image');
            sub = [ceil(app.Rectangle1.Position(2)),ceil(app.Rectangle1.Position(1))];
            ind = sub2ind(size(app.UIAxes1.Children(idx).CData),sub(1),sub(2));
            if app.MarkerTable{sub(1),sub(2)}
                app.MarkerPlots.XData(:,ind) = [0 0 0 0];
                app.MarkerPlots.YData(:,ind) = [0 0 0 0];
                app.MarkerTable{sub(1),sub(2)} = false;
            else
                app.MarkerPlots.XData(:,ind) = [-0.25 0.25 0.25 -0.25]+(sub(2));
                app.MarkerPlots.YData(:,ind) = [-0.25 -0.25 0.25 0.25]+(sub(1));
                app.MarkerTable{sub(1),sub(2)} = true;
            end
        end
        
        
        function populateMarkerPlot(app)
            idx = isgraphics(app.UIAxes1.Children,'Image');
            tmp = app.MarkerTable{:,:};
            ind = find(tmp==1);
            [row,col] = ind2sub(size(app.UIAxes1.Children(idx).CData),ind);
            for i = 1:length(ind)
                app.MarkerPlots.XData(:,ind(i)) = [-0.25 0.25 0.25 -0.25]+(col(i));
                app.MarkerPlots.YData(:,ind(i)) = [-0.25 -0.25 0.25 0.25]+(row(i));
            end
        end
        
        
        function reorderDataColumns(app)
            d = uiprogressdlg(app.UIFigure,'Title','Resorting Data','Indeterminate','on');
            app.CurrentData = app.CurrentData(app.ColumnOrder);
            app.summaryEpoch = app.summaryEpoch(:,:,app.ColumnOrder);
            app.summaryEpoch1 = permute(app.summaryEpoch,[3 2 1]);
            app.CurrentLabels = app.CurrentLabels(app.ColumnOrder);
            app.CurrentTrialPerm = app.CurrentTrialPerm(app.ColumnOrder);
            d.Title = 'Resetting Figures';
            close(d)
        end
        
        
        function reorderDataRows(app)
            d = uiprogressdlg(app.UIFigure,'Title','Resorting Data','Indeterminate','on');
            app.CurrentTable = app.OriginalTable(app.RowOrder,:);
            app.GroupLabels = app.CurrentTable.Label;
            app.GroupIndices = num2cell(app.CurrentTable.MatrixID);
            app.ContactLabels = app.CurrentTable.Label;
            app.ContactIndices = app.RowOrder;
            for i = 1:length(app.ColumnOrder)
                app.CurrentData{i} = permute(app.OriginalData{app.ColumnOrder(i)}(app.ContactIndices,:,:),[3 2 1]);
                app.CurrentTrialPerm{i} = app.DefaultTrialPerm{i}(:,app.ContactIndices);
            end

            close(d)
        end
        
        
        function PerformSIGNIProcessing(app)
            d = uiprogressdlg(app.UIFigure,'Title','Performing SIGNI');
            % Remove Artifact from Signals
            d.Message = 'Removing Stimulation Artifact';
            d.Value = 0;
            data = app.CurrentData;
            stimWindowIdx = app.xAxis>=0 & app.xAxis<=5.1;
            preStimWindowIdx = app.xAxis>=-5.6 & app.xAxis<0;
            postStimWindowIdx = app.xAxis>5 & app.xAxis<=10.1;
            weight = linspace(0,1,sum(stimWindowIdx));
            for i = 1:length(data)
                preStim = data{i}(:,preStimWindowIdx,:);
                preStim = flip(preStim.*weight,2);
                
                postStim = data{i}(:,postStimWindowIdx,:);
                postStim = flip(postStim,2).*weight;
                
                data{i}(:,stimWindowIdx,:) = preStim + postStim;
                d.Value = i/length(data);
            end
            
            % Remove Average Trace
            d.Message = 'Removing Average Trace';
            d.Value = 0;
            for i = 1:length(data)
                tmp = sum(data{i},1);
                data{i} = data{i}-tmp;
                d.Value = i/length(data);
            end
            
            % Zero-phase Non-causal Bandpass Filter (8th Order Butterworth)
            % Between 70Hz and 170Hz
            d.Message = 'Filtering Signals';
            d.Value = 0;
            [b,a] = butter(4,[70 170]/1000,'bandpass');
            for i = 1:length(data)
                tmp = permute(data{i},[2,1,3]);
                tmp = filtfilt(b,a,tmp);
                tmp = abs(hilbert(tmp));
                app.CurrentData{i} = permute(tmp,[2 1 3]);
                d.Value = i/length(data);
            end
            close(d);
        end


        function PerformZScoreProcessing(app,window)
            d = uiprogressdlg(app.UIFigure,'Title','Performing ZScore');
            data = app.OriginalData;
            windowInd = app.xAxis>window(1) & app.xAxis<window(2);
            d.Message = 'ZScore Adjusting Each Trial';
            d.Value = 0;
            for i = 1:length(data)
                perTrialPrestimMean = mean(data{i}(app.RowOrder,windowInd,:),2);
                perTrialPrestimStd = std(data{i}(app.RowOrder,windowInd,:),[],2);
                tmp = (data{i}(app.RowOrder,:,:)-perTrialPrestimMean)./perTrialPrestimStd;
                data{i} = sqrt(tmp.^2);
                data{i} = permute(data{i},[3 2 1]);
                d.Value = i/length(data);
            end
            app.CurrentData = data;
            close(d);
        end
        

        function createContactLabels(app,axHandleName)
            a = cellfun(@length,app.GroupIndices);
            a = cumsum(a(:));
            b = [1; a(1:end-1)+1];
            indices = [b a];
            mp=zeros(length(indices),1);
            for i=1:length(indices)
                mp(i)=(indices(i,1)+indices(i,2))/2;
            end
            app.(axHandleName).YTick = mp;
            app.(axHandleName).YTickLabel = app.GroupLabels;
            app.(axHandleName).YAxis.FontSize = ceil(app.(axHandleName).InnerPosition(4)/numel(mp));
        end

    end
    
    methods (Access = public)
        
        function updatePlots(app,rowIndex,columnIndex)
            if nargin==3
                row = rowIndex;
                col = columnIndex;
            elseif nargin==2
                row = rowIndex;
                col = ceil(app.Rectangle1.Position(1));
            else
                row = ceil(app.Rectangle1.Position(2));
                col = ceil(app.Rectangle1.Position(1));
            end
            app.Rectangle1.Position = [col-0.5 row-0.5 1 1];
            
            switch app.SelectedDimension
                case 'Channels'
                    app.Rectangle2.Position(2) = row-0.5;
                    
                    if app.CurrentColumn~=col
                        ind = isgraphics(app.UIAxes2.Children,'Image');
                        app.UIAxes2.Children(ind).CData = app.summaryEpoch(:,:,col);
                        app.UIAxes2.Title.String = app.CurrentLabels{col};
                        
                        if app.ManualButton_2.Value==1
                            app.UIAxes2.CLim = [app.LowerColorAxisEditField_2.Value app.UpperColorAxisEditField_2.Value];
                        end

                        if ~isempty(app.GlassBrainApp) && isvalid(app.GlassBrainApp) && ~isempty(app.MarkerTable)
%                             tmp = false(numel(app.ContactIndices),1);
%                             tmp(app.MarkerIndex) = app.MarkerTable(:,col);
                            tmp = app.MarkerTable{:,col};
                            highlightMarkedElectrodes(app.GlassBrainApp,tmp)
                        end

                    end
                    
                    ind = isgraphics(app.UIAxes3.Children,'Image');
                    if length(app.UIAxes3.Children(ind).YData)~=size(app.CurrentData{col},1)
                        app.UIAxes3.Children(ind).YData = 1:size(app.CurrentData{col},1);
                        app.UIAxes3.YLim = [0.5 size(app.CurrentData{col},1)+0.5];
                    end
                    app.UIAxes3.Children(ind).CData = app.CurrentData{col}(app.CurrentTrialPerm{col}(:,row),:,row);
                    app.UIAxes3.Title.String = app.ContactLabels{row};
                    
                    if app.ManualButton_3.Value==1
                        app.UIAxes3.CLim = [app.YLimLowerEditField.Value app.YLimUpperEditField.Value];
                    end
                    
                    yyaxis(app.UIAxes3,'right')
                    app.UIAxes3.Children(isgraphics(app.UIAxes3.Children,'Line')).YData = app.summaryEpoch(row,:,col);
                    yyaxis(app.UIAxes3,'left')
                    
                case 'Experiments'
                    app.Rectangle2.Position(2) = col-0.5;
                    
                    if app.CurrentRow~=row
                        ind = isgraphics(app.UIAxes2.Children,'Image');
                        app.UIAxes2.Children(ind).CData = app.summaryEpoch1(:,:,row);
                        app.UIAxes2.Title.String = app.ContactLabels{row};
                        
                        if app.ManualButton_2.Value==1
                            app.UIAxes2.CLim = [app.LowerColorAxisEditField_2.Value app.UpperColorAxisEditField_2.Value];
                        end
                    end
                    
                    ind = isgraphics(app.UIAxes3.Children,'Image');
                    if length(app.UIAxes3.Children(ind).YData)~=size(app.CurrentData{col},1)
                        app.UIAxes3.Children(ind).YData = 1:size(app.CurrentData{col},1);
                        app.UIAxes3.YLim = [0.5 size(app.CurrentData{col},1)+0.5];
                    end
                    app.UIAxes3.Children(ind).CData = app.CurrentData{col}(app.CurrentTrialPerm{col}(:,row),:,row);
                    app.UIAxes3.Title.String = app.CurrentLabels{col};
                    
                    if app.ManualButton_3.Value==1
                        app.UIAxes3.CLim = [app.YLimLowerEditField.Value app.YLimUpperEditField.Value];
                    end
                    
                    yyaxis(app.UIAxes3,'right')
                    app.UIAxes3.Children(isgraphics(app.UIAxes3.Children,'Line')).YData = app.summaryEpoch1(col,:,row);
                    yyaxis(app.UIAxes3,'left')
            end
            app.CurrentRow = row;
            app.CurrentColumn = col;
            if ~isempty(app.GlassBrainApp)% && app.GlassBrainApp.CurrentPointIndex~=app.CurrentRow
                updateBrainApp(app.GlassBrainApp,row)
            end
            
        end
        

        function setReorderListVariable(app,reorderingVector)
            app.ColumnOrder = reorderingVector;
        end
        

        function setTableGUIVariable(app,list)
            [~,~,reorderingVector] = intersect(list,app.OriginalTable.Label,'stable');
            app.RowOrder = reorderingVector;
            reorderDataRows(app);
            if ~isempty(app.GlassBrainApp) && isvalid(app.GlassBrainApp)
                electrodeCoordinates = [app.CurrentTable.MNI305_x app.CurrentTable.MNI305_y app.CurrentTable.MNI305_z];
                updateElectrodeCoordinates(app.GlassBrainApp,electrodeCoordinates)
            end
            summarizeEpochs(app)
            setupFigures(app)
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure,'center')
            app.UIFigure.Visible = 'on';
        end

        % Selection changed function: ColorScaleButtonGroup
        function ColorScaleButtonGroupSelectionChanged(app, event)
            selectedButton = app.ColorScaleButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Linear'
                    app.UIAxes1.ColorScale = 'linear';
                case 'Logarithmic'
                    app.UIAxes1.ColorScale = 'log';
            end
        end

        % Selection changed function: ColorAxisButtonGroup
        function ColorAxisButtonGroupSelectionChanged(app, event)
            selectedButton = app.ColorAxisButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Auto'
                    app.UIAxes1.CLimMode = 'auto';
                case 'Manual'
                    app.UIAxes1.CLimMode = 'manual';
                    app.UIAxes1.CLim = [app.LowerColorAxisEditField.Value app.UpperColorAxisEditField.Value];
            end
        end

        % Value changed function: LowerColorAxisEditField
        function LowerColorAxisEditFieldValueChanged(app, event)
            selectedButton = app.ColorAxisButtonGroup.SelectedObject;
            if strcmp(selectedButton,'Manual')
                caxis(app.UIAxes1,[app.LowerColorAxisEditField.Value app.UpperColorAxisEditField.Value]);
            end
        end

        % Value changed function: UpperColorAxisEditField
        function UpperColorAxisEditFieldValueChanged(app, event)
            selectedButton = app.ColorAxisButtonGroup.SelectedObject;
            if strcmp(selectedButton,'Manual')
                caxis(app.UIAxes1,[app.LowerColorAxisEditField.Value app.UpperColorAxisEditField.Value]);
            end
        end

        % Selection changed function: ColorAxisButtonGroup_2
        function ColorAxisButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.ColorAxisButtonGroup_2.SelectedObject;
            switch selectedButton.Text
                case 'Auto'
                    app.UIAxes2.CLimMode = 'auto';
                    yyaxis(app.UIAxes3,'right')
                    app.UIAxes3.YLimMode = 'auto';
                    yyaxis(app.UIAxes3,'left')
                case 'Manual'
                    app.UIAxes2.CLimMode = 'manual';
                    app.UIAxes2.CLim = [app.LowerColorAxisEditField_2.Value app.UpperColorAxisEditField_2.Value];
                    yyaxis(app.UIAxes3,'right')
                    app.UIAxes3.YLimMode = 'manual';
                    app.UIAxes3.YLim = [app.LowerColorAxisEditField_2.Value app.UpperColorAxisEditField_2.Value];
                    yyaxis(app.UIAxes3,'left')
            end
        end

        % Value changed function: LowerColorAxisEditField_2
        function LowerColorAxisEditField_2ValueChanged(app, event)
            selectedButton = app.ColorAxisButtonGroup_2.SelectedObject.Text;
            if strcmp(selectedButton,'Manual')
                caxis(app.UIAxes2,[app.LowerColorAxisEditField_2.Value app.UpperColorAxisEditField_2.Value]);
                yyaxis(app.UIAxes3,'right')
                app.UIAxes3.YLim = [app.LowerColorAxisEditField_2.Value app.UpperColorAxisEditField_2.Value];
                yyaxis(app.UIAxes3,'left')
            end
        end

        % Value changed function: UpperColorAxisEditField_2
        function UpperColorAxisEditField_2ValueChanged(app, event)
            selectedButton = app.ColorAxisButtonGroup_2.SelectedObject.Text;
            if strcmp(selectedButton,'Manual')
                caxis(app.UIAxes2,[app.LowerColorAxisEditField_2.Value app.UpperColorAxisEditField_2.Value]);
                yyaxis(app.UIAxes3,'right')
                app.UIAxes3.YLim = [app.LowerColorAxisEditField_2.Value app.UpperColorAxisEditField_2.Value];
                yyaxis(app.UIAxes3,'left')
            end
        end

        % Selection changed function: ColorAxisButtonGroup_3
        function ColorAxisButtonGroup_3SelectionChanged(app, event)
            selectedButton = app.ColorAxisButtonGroup_3.SelectedObject.Text;
            switch selectedButton
                case 'Auto'
                    yyaxis(app.UIAxes3,'right')
                    app.UIAxes3.YLimMode = 'auto';
                    yyaxis(app.UIAxes3,'left')
                    app.UIAxes3.CLimMode = 'auto';
                case 'Manual'
                    app.UIAxes3.CLimMode = 'manual';
                    app.UIAxes3.CLim = [app.YLimLowerEditField.Value app.YLimUpperEditField.Value];
            end
        end

        % Value changed function: YLimLowerEditField
        function YLimLowerEditFieldValueChanged(app, event)
            selectedButton = app.ColorAxisButtonGroup_3.SelectedObject.Text;
            if strcmp(selectedButton,'Manual')
                app.UIAxes3.CLim = [app.YLimLowerEditField.Value app.YLimUpperEditField.Value];
            end
        end

        % Value changed function: YLimUpperEditField
        function YLimUpperEditFieldValueChanged(app, event)
            selectedButton = app.ColorAxisButtonGroup_3.SelectedObject.Text;
            if strcmp(selectedButton,'Manual')
                app.UIAxes3.CLim = [app.YLimLowerEditField.Value app.YLimUpperEditField.Value];
            end
        end

        % Menu selected function: BoundedMenu, FullMenu
        function ViewingWindowButtonGroupSelectionChanged(app, event)
            selectedWindow = event.Source.Text;
            switch selectedWindow
                case 'Full'
                    app.TimeWindowIndices = 1:length(app.xAxis);
                    app.UIAxes3.XLim = [app.xAxis(1) app.xAxis(end)];
                    app.UIAxes2.XLim = [app.xAxis(1) app.xAxis(end)];
                case 'Bounded'
                    [~,a] = min(abs(app.TimeLowerEditField.Value-app.xAxis));
                    [~,b] = min(abs(app.TimeUpperEditField.Value-app.xAxis));
                    app.TimeWindowIndices = a:b;
                    app.UIAxes3.XLim = [app.TimeLowerEditField.Value app.TimeUpperEditField.Value];
                    app.UIAxes2.XLim = [app.TimeLowerEditField.Value app.TimeUpperEditField.Value];
            end
            changeUIAxes1Measure(app)
        end

        % Value changed function: TimeLowerEditField
        function TimeLowerEditFieldValueChanged(app, event)
            yyaxis(app.UIAxes3,'left')
            ind = find(isgraphics(app.UIAxes3.Children,'ConstantLine'));
            [~,ind1] = min([app.UIAxes3.Children(ind).Value]);
            app.UIAxes3.Children(ind(ind1)).Value = app.TimeLowerEditField.Value;
        end

        % Value changed function: TimeUpperEditField
        function TimeUpperEditFieldValueChanged(app, event)
            yyaxis(app.UIAxes3,'left')
            ind = find(isgraphics(app.UIAxes3.Children,'ConstantLine'));
            [~,ind1] = max([app.UIAxes3.Children(ind).Value]);
            app.UIAxes3.Children(ind(ind1)).Value = app.TimeUpperEditField.Value;
        end

        % Menu selected function: AbsoluteMaxMenu, CVMenu, LoadedDataMenu, 
        % ...and 6 other components
        function NewUIAxes1MeasureSelected(app, event)
            app.Axis1Measure = event.Source.Text;
            if strcmp(app.Axis1Measure,'Loaded Data')
                useLoadedUIAxes1Measure(app)
            else
                changeUIAxes1Measure(app)
            end
        end

        % Menu selected function: LoadDataMenu
        function LoadDataButtonPushed(app, event)
            [file,path]=uigetfile('*.*');
            tmp = load(fullfile(path,file));
            fn = fieldnames(tmp);
            app.LoadedPEPData = tmp.(fn{1});
            if file~=0
                app.DataButton.Enable = 1;
                app.LoadedDataMenu.Enable = 1;
            end
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            col = ceil(app.Rectangle1.Position(1));
            row = ceil(app.Rectangle1.Position(2));
            switch key
                case 'leftarrow'
                    if col==1
                        col = size(app.summaryEpoch,3);
                    else
                        col = col-1;
                    end
                case 'rightarrow'
                    if col==size(app.summaryEpoch,3)
                        col = 1;
                    else
                        col = col+1;
                    end
                case 'uparrow'
                    if row==1
                        row = size(app.summaryEpoch,1);
                    else
                        row = row-1;
                    end
                case 'downarrow'
                    if row==size(app.summaryEpoch,1)
                        row = 1;
                    else
                        row = row+1;
                    end
                case 'space'
                    MarkTable(app)
            end
            
            updatePlots(app,row,col)
        end

        % Selection changed function: Dimension
        function DimensionSelectionChanged(app, event)
            app.SelectedDimension = app.Dimension.SelectedObject.Text;
            ind = isgraphics(app.UIAxes2.Children,'ConstantLine');
            delete(app.UIAxes2.Children(ind))
            ind1 = isgraphics(app.UIAxes2.Children,'Image');
            switch app.SelectedDimension
                case 'Channels'
                    app.UIAxes2.YLim = [0.5 size(app.summaryEpoch,1)+0.5];
                    title(app.UIAxes2,app.CurrentLabels{app.Rectangle1.Position(1)+0.5});
                    
                    app.UIAxes2.Children(ind1).CData = app.summaryEpoch(:,:,app.Rectangle1.Position(1)+0.5);
                    app.UIAxes2.Children(ind1).YData = 1:size(app.summaryEpoch,1);
                    
                    title(app.UIAxes3,app.ContactLabels{app.Rectangle1.Position(2)+0.5});
                    
                    createContactLabels(app,'UIAxes2');
                    
                    app.Rectangle2.Position = [app.xAxis(1)+0.5 app.Rectangle1.Position(2) diff(app.UIAxes2.XLim) 1];
                    
                case 'Experiments'
                    app.UIAxes2.YLim = [0.5 size(app.summaryEpoch1,1)+0.5];
                    title(app.UIAxes2,app.ContactLabels{app.Rectangle1.Position(2)+0.5});
                    app.UIAxes2.Children(ind1).YData = 1:size(app.summaryEpoch1,1);
                    app.UIAxes2.Children(ind1).CData = app.summaryEpoch1(:,:,app.Rectangle1.Position(2)+0.5);
                    app.UIAxes2.YTick = 1:size(app.summaryEpoch,3);
                    app.UIAxes2.YTickLabel = app.CurrentLabels;
                    title(app.UIAxes3,app.CurrentLabels{app.Rectangle1.Position(1)+0.5});
                    
                    app.Rectangle2.Position = [app.xAxis(1)+0.5 app.Rectangle1.Position(1) diff(app.UIAxes2.XLim) 1];
                    
            end
        end

        % Menu selected function: ResetMarkersMenu
        function ResetMarkingsButtonPushed(app, event)
            app.MarkerTable = table('Size',[size(app.CurrentTable,1),length(app.UIAxes1.XTickLabel)],...
                'VariableTypes',repmat(cellstr('logical'),[1,length(app.UIAxes1.XTickLabel)]),...
                'VariableNames',app.UIAxes1.XTickLabel,...
                'RowNames',app.CurrentTable.Label);
            app.MarkerPlots.XData(:) = 0;
            app.MarkerPlots.YData(:) = 0;
        end

        % Menu selected function: LoadMarkersMenu
        function LoadMarkingsButtonPushed(app, event)
            if ~isempty(app.SubjectID)
                path = uigetdir(fullfile(app.TaskFolder,app.SubjectID));
            else
                path = uigetdir();
            end
            experiments = app.UIAxes1.XTickLabel;
            experimentMarkers = table('Size',[size(app.CurrentTable,1),length(experiments)],'VariableTypes',repmat(cellstr('logical'),[1,length(experiments)]),'VariableNames',experiments,'RowNames',app.CurrentTable.Label);
            
%             experimentMarkers = cell(length(experiments),1);

%             for i = 1:length(experiments)
%                 tmp = load(fullfile(path,['Threshold_',experiments{i},'.mat']),'PEPOccurrence');
%                 tmp1 = fieldnames(tmp);
%                 experimentMarkers{i} = tmp.(tmp1{1});
%             end
%             experimentMarkers = cat(2,experimentMarkers{:});
%             app.MarkerTable = experimentMarkers(app.ContactIndices,:);

            for i = 1:length(experiments)
                try
                    tmp = load(fullfile(path,['Threshold_',experiments{i},'.mat']));
                    chk = structfun(@istable,tmp);
                    if any(chk)
                        fields = fieldnames(tmp);
                        analysisTable = tmp.(fields{find(chk)});
                        L = vartype('logical');
                        C = vartype('cell');
                        [~,indA,indB] = intersect(app.CurrentTable.Label,analysisTable{:,C},'stable');
%                         tmp = table(analysisTable{:,L},'VariableNames',experiments(i),'RowNames',analysisTable{:,C});
                        experimentMarkers.(experiments{i})(indA) = analysisTable{indB,L};
                    else
                        error('No usable variable found')
                    end
                catch ME
                    uialert(app.UIFigure,ME.message,'Error')
                end
            end
            app.MarkerTable = experimentMarkers;

            populateMarkerPlot(app)
        end

        % Menu selected function: ExportMarkersMenu
        function ExportMarkingsButtonPushed(app, event)
            pathSave = uigetdir();
            for i = 1:size(app.MarkerTable,2)
                PEPOccurrence = app.MarkerTable(:,i);
                filename = ['Threshold_',app.CurrentLabels{i},'.mat'];
                save(fullfile(pathSave,filename),'PEPOccurrence')
            end
        end

        % Menu selected function: ShowHideMeanMenu
        function ShowHideMeanMenuSelected(app, event)
            yyaxis(app.UIAxes3,'right')
            if app.UIAxes3.YAxis(2).Visible == 0
                app.UIAxes3.YAxis(2).Visible = 'on';
                app.UIAxes3.Children.Visible = 'on';
            else
                app.UIAxes3.YAxis(2).Visible = 'off';
                app.UIAxes3.Children.Visible = 'off';
            end
            yyaxis(app.UIAxes3,'left')
        end

        % Menu selected function: LoadMenu
        function SortTrialsButtonPushed(app, event)
            path = uigetdir();
            experiments = app.UIAxes1.XTickLabel;
            tmp = regexp(path,[filesep,'([^',filesep,']+)'],'tokens');
            tmp = vertcat(tmp{:});
            folderName = tmp{end};
            for i = 1:length(experiments)
                tmpDat = load(fullfile(path,[folderName,'_',experiments{i},'.mat']));
                tmpDat1 = fieldnames(tmpDat);
                tmpDat2 = tmpDat.(tmpDat1{1});
                [~,app.CurrentTrialPerm{i}] = sort(tmpDat2);
            end
            app.LoadedTrialPerm = app.CurrentTrialPerm;
            app.LoadedMenu.Enable = 1;
            app.DefaultMenu.Checked = 0;
            app.LoadedMenu.Checked = 1;
            
            updatePlots(app)
        end

        % Menu selected function: DefaultMenu
        function DefaultTrialOrderButtonPushed(app, event)
            app.CurrentTrialPerm = app.DefaultTrialPerm(app.ColumnOrder);
            app.DefaultMenu.Checked = 1;
            app.LoadedMenu.Checked = 0;
            app.VoltageMenu.Checked = 0;
            updatePlots(app)
        end

        % Menu selected function: LoadedMenu
        function LoadedTrialOrderButtonPushed(app, event)
            app.CurrentTrialPerm = app.LoadedTrialPerm;
            app.DefaultMenu.Checked = 0;
            app.LoadedMenu.Checked = 1;
            app.VoltageMenu.Checked = 0;
            updatePlots(app)
        end

        % Menu selected function: VoltageMenu
        function VoltageMenuSelected(app, event)
            tmp = cellfun(@(x) squeeze(x(:,499,:)),app.CurrentData,'Uni',0);
            [~,app.CurrentTrialPerm] = cellfun(@(x) sort(x),tmp,'Uni',0);
            app.VoltageMenu.Checked = 1;
            app.DefaultMenu.Checked = 0;
            app.LoadedMenu.Checked = 0;
            updatePlots(app)
        end

        % Menu selected function: BanksSpecialMenu_2, BipolarMenu_2, 
        % ...and 6 other components
        function RereferencingOptionSelected(app, event)
            app.RereferencingMethod = event.Source.Text;
            RereferenceData(app)
            summarizeEpochs(app)
            setupFigures(app)
        end

        % Menu selected function: RowsMenu
        function RowsMenuSelected(app, event)
            app.SortFilterTableApp = TableGUI(app.CurrentTable,app);
        end

        % Menu selected function: ColumnsMenu
        function ColumnsMenuSelected(app, event)
            app.ReorderDataApp = ReorderList(app.CurrentLabels,app);
            waitfor(app.ReorderDataApp)
            reorderDataColumns(app);
            setupFigures(app);
        end

        % Menu selected function: ResetViewerMenu
        function ResetViewerMenuSelected(app, event)
            resetApp(app)
            summarizeEpochs(app)
            setupFigures(app)
        end

        % Menu selected function: ViewGlassBrainMenu
        function ViewGlassBrainMenuSelected(app, event)
            try
                electrodeCoordinates = [app.CurrentTable.MNI305_x app.CurrentTable.MNI305_y app.CurrentTable.MNI305_z];
            catch
                app.GlassBrainApp = GlassBrainGUI(app);
                return
            end
            app.GlassBrainApp = GlassBrainGUI(app,electrodeCoordinates);
        end

        % Menu selected function: LoadDataMenu_2
        function LoadDataMenu_2Selected(app, event)
            loadData(app)
            resetApp(app)
            summarizeEpochs(app)
            setupFigures(app)
        end

        % Menu selected function: ViewSpectrogramMenu
        function ViewSpectrogramMenuSelected(app, event)
            app.SpectrogramViewerApp = SpectrogramViewer(app);
        end

        % Menu selected function: CRPSbarMenu, MeanMenu, MedianMenu, 
        % ...and 2 other components
        function SelectSummaryMethod(app, event)
            for i = 1:length(app.SummaryMenu.Children)
                app.SummaryMenu.Children(i).Checked = 'off';
            end
            event.Source.Checked = 'on';
            summarizeEpochs(app)
            setupFigures(app)
        end

        % Menu selected function: ShuffleViewerMenu
        function ShuffleViewerMenuSelected(app, event)
            ind1 = isgraphics(app.UIAxes2.Children,'Image');
            holder = app.UIAxes2.Children(ind1).CData;
            
            tmp = permute(app.CurrentData{app.CurrentColumn},[3 2 1]);
            ntrial = size(tmp,3);
            subtrial = round(ntrial*0.1);
            
            for i = 1:200
                app.UIAxes2.Children(ind1).CData = mean(tmp(:,:,randperm(ntrial,subtrial)),3);
                drawnow;
            end
            app.UIAxes2.Children(ind1).CData = holder;
        end

        % Menu selected function: SIGNIMenu
        function SIGNIMenuSelected(app, event)
            PerformSIGNIProcessing(app);
            summarizeEpochs(app);
            setupFigures(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.ReorderDataApp)
            delete(app.SortFilterTableApp)
            delete(app.GlassBrainApp)
            delete(app.BrainViewerApp)
            delete(app)
        end

        % Menu selected function: JetMenu, ParulaMenu
        function ColormapMenuSelected(app, event)
            colormap(app.UIFigure,lower(event.Source.Text));
        end

        % Menu selected function: DebugMenu
        function DebugMenuSelected(app, event)
            keyboard
        end

        % Menu selected function: ZScoreModuleMenu
        function ZScoreModuleMenuSelected(app, event)
            dlgtitle = 'ZScore Window';
            prompt = {'Enter Window Lower Bound:','Enter Window Upper Bound'};
            definput = {'-100','-5'};
            response = inputdlg(prompt,dlgtitle,1,definput);
            if isempty(response)
                return
            end
            window = cell2mat(cellfun(@str2num,response,'Uni',0));
            PerformZScoreProcessing(app,window);
            summarizeEpochs(app);
            setupFigures(app);
        end

        % Menu selected function: BrainViewerMenu
        function BrainViewerMenuSelected(app, event)
            app.BrainViewerApp = BrainViewer(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Epoch Viewer';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadDataMenu_2 = uimenu(app.FileMenu);
            app.LoadDataMenu_2.MenuSelectedFcn = createCallbackFcn(app, @LoadDataMenu_2Selected, true);
            app.LoadDataMenu_2.Text = 'Load Data';
            app.SortFilterMenu = uimenu(app.UIFigure);
            app.SortFilterMenu.Text = 'Sort/Filter';
            app.RowsMenu = uimenu(app.SortFilterMenu);
            app.RowsMenu.MenuSelectedFcn = createCallbackFcn(app, @RowsMenuSelected, true);
            app.RowsMenu.Text = 'Rows';
            app.ColumnsMenu = uimenu(app.SortFilterMenu);
            app.ColumnsMenu.MenuSelectedFcn = createCallbackFcn(app, @ColumnsMenuSelected, true);
            app.ColumnsMenu.Text = 'Columns';
            app.OptionsMenu = uimenu(app.UIFigure);
            app.OptionsMenu.Text = 'Options';
            app.ResetViewerMenu = uimenu(app.OptionsMenu);
            app.ResetViewerMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetViewerMenuSelected, true);
            app.ResetViewerMenu.Text = 'Reset Viewer';
            app.ViewGlassBrainMenu = uimenu(app.OptionsMenu);
            app.ViewGlassBrainMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewGlassBrainMenuSelected, true);
            app.ViewGlassBrainMenu.Text = 'View Glass Brain';
            app.BrainViewerMenu = uimenu(app.OptionsMenu);
            app.BrainViewerMenu.MenuSelectedFcn = createCallbackFcn(app, @BrainViewerMenuSelected, true);
            app.BrainViewerMenu.Text = 'BrainViewer';
            app.RereferenceMenu_2 = uimenu(app.OptionsMenu);
            app.RereferenceMenu_2.Text = 'Rereference';
            app.NoneMenu_2 = uimenu(app.RereferenceMenu_2);
            app.NoneMenu_2.MenuSelectedFcn = createCallbackFcn(app, @RereferencingOptionSelected, true);
            app.NoneMenu_2.Text = 'None';
            app.CARMenu_2 = uimenu(app.RereferenceMenu_2);
            app.CARMenu_2.MenuSelectedFcn = createCallbackFcn(app, @RereferencingOptionSelected, true);
            app.CARMenu_2.Text = 'CAR';
            app.GARMenu_2 = uimenu(app.RereferenceMenu_2);
            app.GARMenu_2.MenuSelectedFcn = createCallbackFcn(app, @RereferencingOptionSelected, true);
            app.GARMenu_2.Text = 'GAR';
            app.PARMenu_2 = uimenu(app.RereferenceMenu_2);
            app.PARMenu_2.MenuSelectedFcn = createCallbackFcn(app, @RereferencingOptionSelected, true);
            app.PARMenu_2.Text = 'PAR';
            app.LaplacianMenu_2 = uimenu(app.RereferenceMenu_2);
            app.LaplacianMenu_2.MenuSelectedFcn = createCallbackFcn(app, @RereferencingOptionSelected, true);
            app.LaplacianMenu_2.Text = 'Laplacian';
            app.BipolarMenu_2 = uimenu(app.RereferenceMenu_2);
            app.BipolarMenu_2.MenuSelectedFcn = createCallbackFcn(app, @RereferencingOptionSelected, true);
            app.BipolarMenu_2.Text = 'Bipolar';
            app.CMRMenu_2 = uimenu(app.RereferenceMenu_2);
            app.CMRMenu_2.MenuSelectedFcn = createCallbackFcn(app, @RereferencingOptionSelected, true);
            app.CMRMenu_2.Text = 'CMR';
            app.BanksSpecialMenu_2 = uimenu(app.RereferenceMenu_2);
            app.BanksSpecialMenu_2.MenuSelectedFcn = createCallbackFcn(app, @RereferencingOptionSelected, true);
            app.BanksSpecialMenu_2.Text = 'Banks Special';
            app.InDevelopmentMenu = uimenu(app.UIFigure);
            app.InDevelopmentMenu.Text = 'In Development';
            app.ViewSpectrogramMenu = uimenu(app.InDevelopmentMenu);
            app.ViewSpectrogramMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewSpectrogramMenuSelected, true);
            app.ViewSpectrogramMenu.Text = 'View Spectrogram';
            app.SIGNIMenu = uimenu(app.InDevelopmentMenu);
            app.SIGNIMenu.MenuSelectedFcn = createCallbackFcn(app, @SIGNIMenuSelected, true);
            app.SIGNIMenu.Text = 'SIGNI';
            app.ChangeColormapMenu = uimenu(app.InDevelopmentMenu);
            app.ChangeColormapMenu.Text = 'Change Colormap';
            app.ParulaMenu = uimenu(app.ChangeColormapMenu);
            app.ParulaMenu.MenuSelectedFcn = createCallbackFcn(app, @ColormapMenuSelected, true);
            app.ParulaMenu.Text = 'Parula';
            app.JetMenu = uimenu(app.ChangeColormapMenu);
            app.JetMenu.MenuSelectedFcn = createCallbackFcn(app, @ColormapMenuSelected, true);
            app.JetMenu.Text = 'Jet';
            app.DebugMenu = uimenu(app.InDevelopmentMenu);
            app.DebugMenu.MenuSelectedFcn = createCallbackFcn(app, @DebugMenuSelected, true);
            app.DebugMenu.Text = 'Debug';
            app.ZScoreModuleMenu = uimenu(app.InDevelopmentMenu);
            app.ZScoreModuleMenu.MenuSelectedFcn = createCallbackFcn(app, @ZScoreModuleMenuSelected, true);
            app.ZScoreModuleMenu.Text = 'Z-Score Module';
            app.Panel1 = uipanel(app.UIFigure);
            app.UIAxes1 = uiaxes(app.Panel1);
            app.ColorScaleButtonGroup = uibuttongroup(app.Panel1);
            app.ColorScaleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColorScaleButtonGroupSelectionChanged, true);
            app.ColorScaleButtonGroup.Title = 'Color Scale';
            app.LinearButton = uitogglebutton(app.ColorScaleButtonGroup);
            app.LinearButton.Text = 'Linear';
            app.LinearButton.Value = true;
            app.LogarithmicButton = uitogglebutton(app.ColorScaleButtonGroup);
            app.LogarithmicButton.Text = 'Logarithmic';
            app.LowerColorAxisEditFieldLabel = uilabel(app.Panel1);
            app.LowerColorAxisEditFieldLabel.Text = 'Lower Color Axis';
            app.UpperColorAxisEditFieldLabel = uilabel(app.Panel1);
            app.UpperColorAxisEditFieldLabel.Text = 'Upper Color Axis';
            app.ColorAxisButtonGroup = uibuttongroup(app.Panel1);
            app.ColorAxisButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColorAxisButtonGroupSelectionChanged, true);
            app.ColorAxisButtonGroup.Title = 'Color Axis';
            app.AutoButton = uitogglebutton(app.ColorAxisButtonGroup);
            app.AutoButton.Text = 'Auto';
            app.AutoButton.Value = true;
            app.ManualButton = uitogglebutton(app.ColorAxisButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.LowerColorAxisEditField = uieditfield(app.Panel1, 'numeric');
            app.LowerColorAxisEditField.ValueChangedFcn = createCallbackFcn(app, @LowerColorAxisEditFieldValueChanged, true);
            app.UpperColorAxisEditField = uieditfield(app.Panel1, 'numeric');
            app.UpperColorAxisEditField.ValueChangedFcn = createCallbackFcn(app, @UpperColorAxisEditFieldValueChanged, true);
            app.Panel2 = uipanel(app.UIFigure);
            app.UIAxes2 = uiaxes(app.Panel2);
            xlabel(app.UIAxes2, 'Time')
            app.LowerColorAxisEditField_2Label = uilabel(app.Panel2);
            app.LowerColorAxisEditField_2Label.Text = 'Lower Color Axis';
            app.UpperColorAxisEditField_2Label = uilabel(app.Panel2);
            app.UpperColorAxisEditField_2Label.Text = 'Upper Color Axis';
            app.Dimension = uibuttongroup(app.Panel2);
            app.Dimension.SelectionChangedFcn = createCallbackFcn(app, @DimensionSelectionChanged, true);
            app.Dimension.Title = 'Dimension';
            app.ChannelsButton = uitogglebutton(app.Dimension);
            app.ChannelsButton.Text = 'Channels';
            app.ChannelsButton.Value = true;
            app.ExperimentsButton = uitogglebutton(app.Dimension);
            app.ExperimentsButton.Text = 'Experiments';
            app.ColorAxisButtonGroup_2 = uibuttongroup(app.Panel2);
            app.ColorAxisButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ColorAxisButtonGroup_2SelectionChanged, true);
            app.ColorAxisButtonGroup_2.Title = 'Color Axis';
            app.AutoButton_2 = uitogglebutton(app.ColorAxisButtonGroup_2);
            app.AutoButton_2.Text = 'Auto';
            app.AutoButton_2.Value = true;
            app.ManualButton_2 = uitogglebutton(app.ColorAxisButtonGroup_2);
            app.ManualButton_2.Text = 'Manual';
            app.LowerColorAxisEditField_2 = uieditfield(app.Panel2, 'numeric');
            app.LowerColorAxisEditField_2.ValueChangedFcn = createCallbackFcn(app, @LowerColorAxisEditField_2ValueChanged, true);
            app.UpperColorAxisEditField_2 = uieditfield(app.Panel2, 'numeric');
            app.UpperColorAxisEditField_2.ValueChangedFcn = createCallbackFcn(app, @UpperColorAxisEditField_2ValueChanged, true);
            app.Panel3 = uipanel(app.UIFigure);
            app.UIAxes3 = uiaxes(app.Panel3);
            xlabel(app.UIAxes3, 'Time')
            ylabel(app.UIAxes3, 'Trials')
            app.ColorAxisButtonGroup_3 = uibuttongroup(app.Panel3);
            app.ColorAxisButtonGroup_3.SelectionChangedFcn = createCallbackFcn(app, @ColorAxisButtonGroup_3SelectionChanged, true);
            app.ColorAxisButtonGroup_3.Title = 'Color Axis';
            app.AutoButton_3 = uitogglebutton(app.ColorAxisButtonGroup_3);
            app.AutoButton_3.Text = 'Auto';
            app.AutoButton_3.Value = true;
            app.ManualButton_3 = uitogglebutton(app.ColorAxisButtonGroup_3);
            app.ManualButton_3.Text = 'Manual';
            app.YLimLowerEditField = uieditfield(app.Panel3, 'numeric');
            app.YLimLowerEditField.ValueChangedFcn = createCallbackFcn(app, @YLimLowerEditFieldValueChanged, true);
            app.YLimUpperEditField = uieditfield(app.Panel3, 'numeric');
            app.YLimUpperEditField.ValueChangedFcn = createCallbackFcn(app, @YLimUpperEditFieldValueChanged, true);
            app.TimeUpperEditField = uieditfield(app.Panel3, 'numeric');
            app.TimeUpperEditField.ValueChangedFcn = createCallbackFcn(app, @TimeUpperEditFieldValueChanged, true);
            app.TimeLowerEditField = uieditfield(app.Panel3, 'numeric');
            app.TimeLowerEditField.ValueChangedFcn = createCallbackFcn(app, @TimeLowerEditFieldValueChanged, true);
            app.LowerLabel = uilabel(app.Panel3);
            app.LowerLabel.Text = 'Lower';
            app.UpperLabel = uilabel(app.Panel3);
            app.UpperLabel.Text = 'Upper';
            app.TimeLabel = uilabel(app.Panel3);
            app.TimeLabel.Text = 'Time';
            app.YLimLabel = uilabel(app.Panel3);
            app.YLimLabel.Text = 'YLim';
            app.UIAxes1ContextMenu = uicontextmenu(app.UIFigure);
            app.SelectMeasureMenu = uimenu(app.UIAxes1ContextMenu);
            app.SelectMeasureMenu.Text = 'Select Measure';
            app.AbsoluteMaxMenu = uimenu(app.SelectMeasureMenu);
            app.AbsoluteMaxMenu.MenuSelectedFcn = createCallbackFcn(app, @NewUIAxes1MeasureSelected, true);
            app.AbsoluteMaxMenu.Text = 'Absolute Max';
            app.MaximumMenu = uimenu(app.SelectMeasureMenu);
            app.MaximumMenu.MenuSelectedFcn = createCallbackFcn(app, @NewUIAxes1MeasureSelected, true);
            app.MaximumMenu.Text = 'Maximum';
            app.MinimumMenu = uimenu(app.SelectMeasureMenu);
            app.MinimumMenu.MenuSelectedFcn = createCallbackFcn(app, @NewUIAxes1MeasureSelected, true);
            app.MinimumMenu.Text = 'Minimum';
            app.RangeMenu = uimenu(app.SelectMeasureMenu);
            app.RangeMenu.MenuSelectedFcn = createCallbackFcn(app, @NewUIAxes1MeasureSelected, true);
            app.RangeMenu.Text = 'Range';
            app.RMSMenu = uimenu(app.SelectMeasureMenu);
            app.RMSMenu.MenuSelectedFcn = createCallbackFcn(app, @NewUIAxes1MeasureSelected, true);
            app.RMSMenu.Text = 'RMS';
            app.RMSPeakMenu = uimenu(app.SelectMeasureMenu);
            app.RMSPeakMenu.MenuSelectedFcn = createCallbackFcn(app, @NewUIAxes1MeasureSelected, true);
            app.RMSPeakMenu.Text = 'RMS*Peak';
            app.SNRMenu = uimenu(app.SelectMeasureMenu);
            app.SNRMenu.MenuSelectedFcn = createCallbackFcn(app, @NewUIAxes1MeasureSelected, true);
            app.SNRMenu.Text = 'SNR';
            app.CVMenu = uimenu(app.SelectMeasureMenu);
            app.CVMenu.MenuSelectedFcn = createCallbackFcn(app, @NewUIAxes1MeasureSelected, true);
            app.CVMenu.Text = 'CV';
            app.LoadedDataMenu = uimenu(app.SelectMeasureMenu);
            app.LoadedDataMenu.MenuSelectedFcn = createCallbackFcn(app, @NewUIAxes1MeasureSelected, true);
            app.LoadedDataMenu.Enable = 'off';
            app.LoadedDataMenu.Text = 'Loaded Data';
            app.MarkersMenu = uimenu(app.UIAxes1ContextMenu);
            app.MarkersMenu.Text = 'Markers';
            app.LoadMarkersMenu = uimenu(app.MarkersMenu);
            app.LoadMarkersMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMarkingsButtonPushed, true);
            app.LoadMarkersMenu.Text = 'Load Markers';
            app.ExportMarkersMenu = uimenu(app.MarkersMenu);
            app.ExportMarkersMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportMarkingsButtonPushed, true);
            app.ExportMarkersMenu.Text = 'Export Markers';
            app.ResetMarkersMenu = uimenu(app.MarkersMenu);
            app.ResetMarkersMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetMarkingsButtonPushed, true);
            app.ResetMarkersMenu.Text = 'Reset Markers';
            app.LoadDataMenu = uimenu(app.UIAxes1ContextMenu);
            app.LoadDataMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataMenu.Text = 'Load Data';
            % Assign app.UIAxes1ContextMenu
            app.Panel1.ContextMenu = app.UIAxes1ContextMenu;
            app.UIAxes1.ContextMenu = app.UIAxes1ContextMenu;
            app.UIAxes2ContextMenu = uicontextmenu(app.UIFigure);
            app.SummaryMenu = uimenu(app.UIAxes2ContextMenu);
            app.SummaryMenu.Text = 'Summary';
            app.MeanMenu = uimenu(app.SummaryMenu);
            app.MeanMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectSummaryMethod, true);
            app.MeanMenu.Checked = 'on';
            app.MeanMenu.Text = 'Mean';
            app.PhaseSynchronyMenu = uimenu(app.SummaryMenu);
            app.PhaseSynchronyMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectSummaryMethod, true);
            app.PhaseSynchronyMenu.Text = 'Phase Synchrony';
            app.MedianMenu = uimenu(app.SummaryMenu);
            app.MedianMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectSummaryMethod, true);
            app.MedianMenu.Text = 'Median';
            app.RMSMenu_2 = uimenu(app.SummaryMenu);
            app.RMSMenu_2.MenuSelectedFcn = createCallbackFcn(app, @SelectSummaryMethod, true);
            app.RMSMenu_2.Text = 'RMS';
            app.CRPSbarMenu = uimenu(app.SummaryMenu);
            app.CRPSbarMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectSummaryMethod, true);
            app.CRPSbarMenu.Text = 'CRP Sbar';
            app.ShuffleViewerMenu = uimenu(app.UIAxes2ContextMenu);
            app.ShuffleViewerMenu.MenuSelectedFcn = createCallbackFcn(app, @ShuffleViewerMenuSelected, true);
            app.ShuffleViewerMenu.Text = 'ShuffleViewer';
            % Assign app.UIAxes2ContextMenu
            app.Panel2.ContextMenu = app.UIAxes2ContextMenu;
            app.UIAxes2.ContextMenu = app.UIAxes2ContextMenu;
            app.UIAxes3ContextMenu = uicontextmenu(app.UIFigure);
            app.ShowHideMeanMenu = uimenu(app.UIAxes3ContextMenu);
            app.ShowHideMeanMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowHideMeanMenuSelected, true);
            app.ShowHideMeanMenu.Text = 'Show/Hide Mean';
            app.ViewingWindowMenu = uimenu(app.UIAxes3ContextMenu);
            app.ViewingWindowMenu.Text = 'Viewing Window';
            app.FullMenu = uimenu(app.ViewingWindowMenu);
            app.FullMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewingWindowButtonGroupSelectionChanged, true);
            app.FullMenu.Text = 'Full';
            app.BoundedMenu = uimenu(app.ViewingWindowMenu);
            app.BoundedMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewingWindowButtonGroupSelectionChanged, true);
            app.BoundedMenu.Text = 'Bounded';
            app.SortTrialsMenu = uimenu(app.UIAxes3ContextMenu);
            app.SortTrialsMenu.Text = 'Sort Trials';
            app.LoadMenu = uimenu(app.SortTrialsMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @SortTrialsButtonPushed, true);
            app.LoadMenu.Text = 'Load';
            app.DefaultMenu = uimenu(app.SortTrialsMenu);
            app.DefaultMenu.MenuSelectedFcn = createCallbackFcn(app, @DefaultTrialOrderButtonPushed, true);
            app.DefaultMenu.Separator = 'on';
            app.DefaultMenu.Checked = 'on';
            app.DefaultMenu.Text = 'Default';
            app.LoadedMenu = uimenu(app.SortTrialsMenu);
            app.LoadedMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadedTrialOrderButtonPushed, true);
            app.LoadedMenu.Enable = 'off';
            app.LoadedMenu.Text = 'Loaded';
            app.VoltageMenu = uimenu(app.SortTrialsMenu);
            app.VoltageMenu.MenuSelectedFcn = createCallbackFcn(app, @VoltageMenuSelected, true);
            app.VoltageMenu.Text = 'Voltage';
            % Assign app.UIAxes3ContextMenu
            app.Panel3.ContextMenu = app.UIAxes3ContextMenu;
            app.UIAxes3.ContextMenu = app.UIAxes3ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EpochViewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end