classdef ReorderList < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ReorderListUIFigure   matlab.ui.Figure
        OriginalListBoxLabel  matlab.ui.control.Label
        OriginalListBox       matlab.ui.control.ListBox
        SubmitButton          matlab.ui.control.Button
        NewListBoxLabel       matlab.ui.control.Label
        NewListBox            matlab.ui.control.ListBox
        DeleteButton          matlab.ui.control.Button
        AddToNewList          matlab.ui.control.Button
        CalculatePermutationVectorButton  matlab.ui.control.Button
        OriginalNewLampLabel  matlab.ui.control.Label
        OriginalNewLamp       matlab.ui.control.Lamp
    end

    
    properties (Access = public)
        OrigList
        NewList
        PermutationVector
        CallingApp
        
    end
    
    methods (Access = private)
        function CheckIfSameSet(app)
            if length(app.OrigList)==length(app.NewList) && isempty(setdiff(app.OrigList,app.NewList))
                app.OriginalNewLamp.Color = [0 1 0];
            else
                app.OriginalNewLamp.Color = [0 0 0];
            end
        end
        
        function CalculatePermutationVector(app)
            [~,~,Pao] = intersect(app.NewList,app.OrigList,'stable');
            app.PermutationVector = Pao;
%             La = app.OrigList;
%             Lo = app.NewList;
%             [Li,Pai] = sort(La);
% %             [~,Pia] = sort(Li(Pai));
%             [~,Poi] = sort(Lo);
%             [~,Pio] = sort(Li(Poi));
%             app.PermutationVector = Pai(Pio);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, list, callingapp)
            app.ReorderListUIFigure.Visible = 'off';
            
            try
                app.CallingApp = callingapp;
            catch
            end
            
            if ~isempty(app.CallingApp)
                pos1 = app.CallingApp.UIFigure.Position;
                pos2 = app.ReorderListUIFigure.Position;
                posF = [pos1(1)+0.5*(pos1(3)-pos2(3)) pos1(2)+0.5*(pos1(4)-pos2(4))];
                movegui(app.ReorderListUIFigure,posF)
            else
                movegui(app.ReorderListUIFigure,'center')
            end
            app.ReorderListUIFigure.Visible = 'on';
            
            app.OrigList = list;
            app.OriginalListBox.Items = app.OrigList;
            app.OriginalListBox.ItemsData = 1:length(app.OrigList);
            app.OrigList = app.OriginalListBox.Items;
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            CalculatePermutationVector(app)
            if ~isempty(app.CallingApp)
                app.ReorderListUIFigure.Visible = 'off';
                setReorderListVariable(app.CallingApp,app.PermutationVector)
                ReorderListUIFigureCloseRequest(app)
            else
                assignin('base','ReorderedList',app.NewList)
                assignin('base','PermutationVector',app.PermutationVector)
                ReorderListUIFigureCloseRequest(app)
            end
        end

        % Value changed function: OriginalListBox
        function OriginalListBoxValueChanged(app, event)
            tmp = app.OriginalListBox.Value;
            app.OriginalListBox.Value = sort(tmp);
        end

        % Button pushed function: AddToNewList
        function AddToNewListButtonPushed(app, event)
            tmp = app.OriginalListBox.Items(app.OriginalListBox.Value);
            if ~isempty(app.NewListBox.Value)
                app.NewList = [app.NewList(1:app.NewListBox.Value) tmp app.NewList(app.NewListBox.Value+1:length(app.NewList))];
            else
                app.NewList = [app.NewList tmp];
            end
            app.NewListBox.Items = app.NewList;
            app.NewListBox.ItemsData = 1:length(app.NewList);
            CheckIfSameSet(app)
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            app.NewList(app.NewListBox.Value) = [];
            if ~isempty(app.NewList)
                if min(app.NewListBox.Value)~=1
                    app.NewListBox.Value = min(app.NewListBox.Value)-1;
                else
                    app.NewListBox.Value = 1;
                end
                app.NewListBox.ItemsData = 1:length(app.NewList);
            end
            app.NewListBox.Items = app.NewList;
            CheckIfSameSet(app)
        end

        % Button pushed function: CalculatePermutationVectorButton
        function CalculatePermutationVectorButtonPushed(app, event)
            CalculatePermutationVector(app)
        end

        % Close request function: ReorderListUIFigure
        function ReorderListUIFigureCloseRequest(app, event)
            if ~isempty(app.CallingApp)
                app.CallingApp.ReorderDataApp = [];
            end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ReorderListUIFigure = uifigure('Visible', 'off');
            app.ReorderListUIFigure.Name = 'Reorder List';
            app.ReorderListUIFigure.CloseRequestFcn = createCallbackFcn(app, @ReorderListUIFigureCloseRequest, true);
            app.OriginalListBoxLabel = uilabel(app.ReorderListUIFigure);
            app.OriginalListBoxLabel.Text = 'Original';
            app.OriginalListBox = uilistbox(app.ReorderListUIFigure);
            app.OriginalListBox.Items = {};
            app.OriginalListBox.Multiselect = 'on';
            app.OriginalListBox.ValueChangedFcn = createCallbackFcn(app, @OriginalListBoxValueChanged, true);
            app.OriginalListBox.Value = {};
            app.SubmitButton = uibutton(app.ReorderListUIFigure, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.NewListBoxLabel = uilabel(app.ReorderListUIFigure);
            app.NewListBoxLabel.Text = 'New';
            app.NewListBox = uilistbox(app.ReorderListUIFigure);
            app.NewListBox.Items = {};
            app.NewListBox.Multiselect = 'on';
            app.NewListBox.Value = {};
            app.DeleteButton = uibutton(app.ReorderListUIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.AddToNewList = uibutton(app.ReorderListUIFigure, 'push');
            app.AddToNewList.ButtonPushedFcn = createCallbackFcn(app, @AddToNewListButtonPushed, true);
            app.AddToNewList.Text = '->';
            app.CalculatePermutationVectorButton = uibutton(app.ReorderListUIFigure, 'push');
            app.CalculatePermutationVectorButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatePermutationVectorButtonPushed, true);
            app.CalculatePermutationVectorButton.Text = {'Calculate'; ' Permuation Vector'};
            app.OriginalNewLampLabel = uilabel(app.ReorderListUIFigure);
            app.OriginalNewLampLabel.Text = 'Original = New';
            app.OriginalNewLamp = uilamp(app.ReorderListUIFigure);
            % Show the figure after all components are created
            app.ReorderListUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ReorderList(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ReorderListUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ReorderListUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ReorderListUIFigure)
        end
    end
end