classdef SortTableGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SortUIFigure            matlab.ui.Figure
        UITable                 matlab.ui.control.Table
        AddButton               matlab.ui.control.Button
        RemoveButton            matlab.ui.control.Button
        OKButton                matlab.ui.control.Button
        CancelButton            matlab.ui.control.Button
        AddlevelstosortbyLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        TableData
        Columns
        Order
        SelectedRow
    end
    
    properties (Access = private)
        CallingApp
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, tableData)
            app.SortUIFigure.Visible = 'off';
            
            if ~isempty(mainapp)
                app.CallingApp = mainapp;
                fn = fieldnames(app.CallingApp);
                pos1 = app.CallingApp.(fn{contains(fn,'UIFigure')}).Position;
                pos2 = app.SortUIFigure.Position;
                posF = [pos1(1)+0.5*(pos1(3)-pos2(3)) pos1(2)+0.5*(pos1(4)-pos2(4))];
                movegui(app.SortUIFigure,posF)
            else
                movegui(app.SortUIFigure,'center')
            end
            app.SortUIFigure.Visible = 'on';
            
            app.TableData = tableData;
            app.Columns = categorical(app.TableData.Properties.VariableNames(1),[app.TableData.Properties.VariableNames],'Protected',true);
            app.Order = categorical({'A to Z'},{'A to Z','Z to A'}','Protected',true);
            
            T = table({'Sort by'},app.Columns,app.Order,'VariableNames',{'Label','Columns','Order'});
            app.UITable.Data = T;
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            T = table({'Then by'},app.Columns,app.Order,'VariableNames',{'Label','Columns','Order'});
            app.UITable.Data = [app.UITable.Data;T];
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            %This version uses undocumented functionality. The benefit is
            %that it uses the Hidden UITable Selection properties
            %maintained by the object instead of storing/tracking/deleting a
            %separate selection property. This approach also deselects the
            %UITable Select
            rows = unique(app.UITable.Selection(:,1));
            if isempty(rows)||size(app.UITable.Data,1)==1
                return
            elseif size(app.UITable.Data,1)==length(rows)
                app.UITable.Data(rows(2:end),:) = [];
            else
                app.UITable.Data(rows,:) = [];
                app.UITable.Data(1,1) = cellstr('Sort by');
            end
            app.UITable.Selection = [];
            %             app.UITable.SelectionStorage.Selection = []; %Couldn't get
            %             this part to work. Perhaps a protected attribute?
            
            %             if isempty(app.SelectedRow)||size(app.UITable.Data,1)==1
            %                 return
            %             elseif size(app.UITable.Data,1)==length(app.SelectedRow)
            %                 app.UITable.Data(app.SelectedRow(2:end),:) = [];
            %             else
            %                 app.UITable.Data(app.SelectedRow,:) = [];
            %                 app.UITable.Data(1,1) = cellstr('Sort by');
            %             end
            %             app.SelectedRow = [];
            
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            order = cell(size(app.UITable.Data,1),1);
            order(app.UITable.Data{:,3}=='Z to A') = cellstr('descend');
            order(app.UITable.Data{:,3}=='A to Z') = cellstr('ascend');
            [~,idx] = sortrows(app.TableData,cellstr(app.UITable.Data.Columns),order);
            if ~isempty(app.CallingApp)
                sortTableGUIRows(app.CallingApp,idx)
                SortUIFigureCloseRequest(app)
            else
                assignin('base','idx',idx)
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            SortUIFigureCloseRequest(app)
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            app.SelectedRow = event.Indices(:,1);
        end

        % Close request function: SortUIFigure
        function SortUIFigureCloseRequest(app, event)
            if ~isempty(app.CallingApp)
                app.CallingApp.SortApp = [];
            end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SortUIFigure = uifigure('Visible', 'off');
            app.SortUIFigure.Name = 'Sort';
            app.SortUIFigure.CloseRequestFcn = createCallbackFcn(app, @SortUIFigureCloseRequest, true);
            app.UITable = uitable(app.SortUIFigure);
            app.UITable.ColumnName = {''; 'Column'; 'Order'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true true];
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.AddButton = uibutton(app.SortUIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = '+';
            app.RemoveButton = uibutton(app.SortUIFigure, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = '-';
            app.OKButton = uibutton(app.SortUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.SortUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.AddlevelstosortbyLabel = uilabel(app.SortUIFigure);
            app.AddlevelstosortbyLabel.Text = 'Add levels to sort by:';
            % Show the figure after all components are created
            app.SortUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SortTableGUI(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SortUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.SortUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SortUIFigure)
        end
    end
end