classdef FilterTableGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FilterUIFigure  matlab.ui.Figure
        FilterButton    matlab.ui.control.Button
        UITable         matlab.ui.control.Table
    end

    
    properties (Access = public)
        SelectedColumn
    end
    
    properties (Access = private)
        MainApp
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, selectedColumn)
            app.FilterUIFigure.Visible = 'off';
            
            if ~isempty(mainapp)
                app.MainApp = mainapp;
                fn = fieldnames(app.MainApp);
                pos1 = app.MainApp.(fn{contains(fn,'UIFigure')}).Position;
                pos2 = app.FilterUIFigure.Position;
                posF = [pos1(1)+0.5*(pos1(3)-pos2(3)) pos1(2)+0.5*(pos1(4)-pos2(4))];
                movegui(app.FilterUIFigure,posF)
            else
                movegui(app.FilterUIFigure,'center')
            end
            app.FilterUIFigure.Visible = 'on';
            
            if ~isempty(selectedColumn)
                app.SelectedColumn = selectedColumn;
%                 elements = table2cell(unique(selectedColumn));
                elements = unique(selectedColumn,'stable');
                selectedElements = table(true(size(elements,1),1));
                app.UITable.Data = [elements,selectedElements];
                app.UITable.ColumnName(1) = app.SelectedColumn.Properties.VariableNames(1);
            end
        end

        % Close request function: FilterUIFigure
        function FilterUIFigureCloseRequest(app, event)
            if ~isempty(app.MainApp)
                app.MainApp.FilterApp = [];
            end
            delete(app)
        end

        % Button pushed function: FilterButton
        function FilterButtonPushed(app, event)
            if ~isempty(app.MainApp)
                elements = app.UITable.Data.(app.UITable.ColumnName{1})(app.UITable.Data{:,2},1);
                idx = ismember(app.MainApp.UITable.Data.(app.UITable.ColumnName{1}),elements);
                filterTableGUIRows(app.MainApp,idx)
                FilterUIFigureCloseRequest(app)
            else
                assignin('base','idx',idx)
                FilterUIFigureCloseRequest(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FilterUIFigure = uifigure('Visible', 'off');
            app.FilterUIFigure.Name = 'Filter';
            app.FilterUIFigure.CloseRequestFcn = createCallbackFcn(app, @FilterUIFigureCloseRequest, true);
            app.UITable = uitable(app.FilterUIFigure);
            app.UITable.ColumnName = {''; ''};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true];
            app.FilterButton = uibutton(app.FilterUIFigure, 'push');
            app.FilterButton.ButtonPushedFcn = createCallbackFcn(app, @FilterButtonPushed, true);
            app.FilterButton.Text = 'Filter';
            % Show the figure after all components are created
            app.FilterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FilterTableGUI(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.FilterUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.FilterUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FilterUIFigure)
        end
    end
end