classdef SpectrogramViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UIAxes    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        SpectrogramData
        Spectra = 0:50
        SF = 2000
    end
    
    properties (Access = public)
        CallingApp
    end
    
    methods (Access = private)
        
        function updatePlot(app)
            ind = isgraphics(app.UIAxes.Children,'Image');
            app.UIAxes.Children(ind).CData = app.SpectrogramData{app.CallingApp.CurrentColumn}(:,:,app.CallingApp.CurrentRow);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingapp)
            app.UIFigure.Visible = 'off';
            
            try
                app.CallingApp = callingapp;
            catch
            end
            
            if ~isempty(app.CallingApp)
                pos1 = app.CallingApp.UIFigure.Position;
                pos2 = app.UIFigure.Position;
                posF = [pos1(1)+0.5*(pos1(3)-pos2(3)) pos1(2)+0.5*(pos1(4)-pos2(4))];
                movegui(app.UIFigure,posF)
            else
                movegui(app.UIFigure,'center')
            end
            app.UIFigure.Visible = 'on';


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpectrogramViewer(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end