classdef TableGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TableUIFigure                  matlab.ui.Figure
        DevelopmentMenu                matlab.ui.container.Menu
        SortByDistanceFromContactMenu  matlab.ui.container.Menu
        UITable                        matlab.ui.control.Table
        SortButton                     matlab.ui.control.Button
        CloseButton                    matlab.ui.control.Button
        ApplyButton                    matlab.ui.control.Button
        FilterButton                   matlab.ui.control.StateButton
        ResetButton                    matlab.ui.control.Button
        DeleteRowButton                matlab.ui.control.Button
    end

    
    properties (Access = public)
        OriginalTableData
        OrderingIndex
        FilteringIndex
        Test
        SelectionIndex
    end
    
    properties (Access = public)
        CallingApp
        SortApp
        FilterApp
    end
    
    
    methods (Access = public)
        
        function sortTableGUIRows(app,idx)
            t = app.UITable.Data;
            app.UITable.Data = t(idx,:);
            app.OrderingIndex = idx;
            app.ApplyButton.Enable = 'on';
        end
        
        function filterTableGUIRows(app,idx)
            t = app.UITable.Data;
            app.UITable.Data = t(idx,:);
            app.FilteringIndex = idx;
            app.ApplyButton.Enable = 'on';
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, tableData, callingApp)
            try
                app.CallingApp = callingApp;
            catch
            end
            
            app.OriginalTableData = tableData;
            app.UITable.Data = app.OriginalTableData;
            app.UITable.ColumnName = app.OriginalTableData.Properties.VariableNames;
        end

        % Button pushed function: SortButton
        function SortButtonPushed(app, event)
            app.SortApp = SortTableGUI(app,app.UITable.Data);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.UITable.Data = app.OriginalTableData;
            app.ApplyButton.Enable = 'on';
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            app.SelectionIndex = event.Indices;
            app.Test = event;
            if size(app.SelectionIndex,1)==size(app.UITable.Data,1) && app.FilterButton.Value
                column = app.SelectionIndex(1,2);
                selectedColumn = app.UITable.Data(:,column);
                app.FilterApp = FilterTableGUI(app,selectedColumn);
            end
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            label = app.UITable.Data.Label; %The name of each item seems to be the most invariant property
            if ~isempty(app.CallingApp)
                setTableGUIVariable(app.CallingApp,label);
            else
                assignin('base','idx',label)
            end
            app.ApplyButton.Enable = 'off';
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            TableUIFigureCloseRequest(app);
        end

        % Close request function: TableUIFigure
        function TableUIFigureCloseRequest(app, event)
            delete(app.SortApp)
            delete(app.FilterApp)
            if ~isempty(app.CallingApp)
                app.CallingApp.SortFilterTableApp = [];
            end
            delete(app)            
        end

        % Button pushed function: DeleteRowButton
        function DeleteRowButtonPushed(app, event)
            app.UITable.Data(app.SelectionIndex(:,1),:) = [];
            app.ApplyButton.Enable = 'on';
        end

        % Menu selected function: SortByDistanceFromContactMenu
        function SortByDistanceFromContactMenuSelected(app, event)
            idxL = listdlg('ListString',app.UITable.Data.Label);
            stimCoord = [app.UITable.Data.Coord_x(idxL) app.UITable.Data.Coord_y(idxL) app.UITable.Data.Coord_z(idxL)];
            
            % Sort by Distance from Stim Contact
            allCoord = [app.UITable.Data.Coord_x app.UITable.Data.Coord_y app.UITable.Data.Coord_z];
            coordDist = sqrt(sum((allCoord-stimCoord).^2,2));
            [~,coordSortIdx] = sort(coordDist);
            sortTableGUIRows(app,coordSortIdx);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TableUIFigure = uifigure('Visible', 'off');
            app.TableUIFigure.Name = 'Table';
            app.TableUIFigure.CloseRequestFcn = createCallbackFcn(app, @TableUIFigureCloseRequest, true);
            app.DevelopmentMenu = uimenu(app.TableUIFigure);
            app.DevelopmentMenu.Text = 'Development';
            app.SortByDistanceFromContactMenu = uimenu(app.DevelopmentMenu);
            app.SortByDistanceFromContactMenu.MenuSelectedFcn = createCallbackFcn(app, @SortByDistanceFromContactMenuSelected, true);
            app.SortByDistanceFromContactMenu.Text = 'SortByDistanceFromContact';
            app.UITable = uitable(app.TableUIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.SortButton = uibutton(app.TableUIFigure, 'push');
            app.SortButton.ButtonPushedFcn = createCallbackFcn(app, @SortButtonPushed, true);
            app.SortButton.Text = 'Sort';
            app.CloseButton = uibutton(app.TableUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.ApplyButton = uibutton(app.TableUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Enable = 'off';
            app.ApplyButton.Text = 'Apply';
            app.FilterButton = uibutton(app.TableUIFigure, 'state');
            app.FilterButton.Text = 'Filter';
            app.ResetButton = uibutton(app.TableUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.DeleteRowButton = uibutton(app.TableUIFigure, 'push');
            app.DeleteRowButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteRowButtonPushed, true);
            app.DeleteRowButton.Text = 'Delete Row';
            % Show the figure after all components are created
            app.TableUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TableGUI(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.TableUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.TableUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TableUIFigure)
        end
    end
end